classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ColorBlindSimulationTab      matlab.ui.container.Tab
        TritanopiaLabel              matlab.ui.control.Label
        DeuteranopiaLabel            matlab.ui.control.Label
        ProtanopiaLabel              matlab.ui.control.Label
        NormalVisionLabel            matlab.ui.control.Label
        ImageNormal                  matlab.ui.control.Image
        ImageDeuteranopia            matlab.ui.control.Image
        ImageTritanopia              matlab.ui.control.Image
        LoadNormal                   matlab.ui.control.Button
        ImageProtanopia              matlab.ui.control.Image
        ImageRecoloringTab           matlab.ui.container.Tab
        Recolor                      matlab.ui.control.Button
        ColorDifferenceLabel         matlab.ui.control.Label
        RecoloredImageLabel          matlab.ui.control.Label
        NormalVisionLabel_2          matlab.ui.control.Label
        ImageDifference              matlab.ui.control.Image
        ImageInput                   matlab.ui.control.Image
        ColorBlindTypeDropDown       matlab.ui.control.DropDown
        ColorBlindTypeDropDownLabel  matlab.ui.control.Label
        LoadInput                    matlab.ui.control.Button
        ImageOutput                  matlab.ui.control.Image
    end

    properties (Access = private)
        currentImage
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadInput
        function LoadInputPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;
            end
            
            % Read the image from the selected file
            imgPath = fullfile(path, file);
            app.currentImage = im2double(imread(imgPath));
            
            % Display the original image
            app.ImageInput.ImageSource = imgPath;
        end

        % Button pushed function: LoadNormal
        function LoadNormalButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;
            end
        
            % Read the image from the selected file
            imgPath = fullfile(path, file);
            app.ImageNormal.ImageSource = imgPath;
        
            % Perform daltonization
            [RGB_p, RGB_d, RGB_t, ~] = daltonize(imgPath);
        
            % Assign processed images to app components
            app.ImageProtanopia.ImageSource = RGB_p;
            app.ImageDeuteranopia.ImageSource = RGB_d;
            app.ImageTritanopia.ImageSource = RGB_t;
        end

        % Button pushed function: Recolor
        function RecolorButtonPushed(app, event)
            % Process the image based on the selected type
            dropdownValue = app.ColorBlindTypeDropDown.Value;
            switch dropdownValue
                case 'Protanopia'
                    enhancedImg = daltonizeImage(app.currentImage, 'protanopia');
                case 'Deuteranopia'
                    enhancedImg = daltonizeImage(app.currentImage, 'deuteranopia');
                case 'Tritanopia'
                    enhancedImg = daltonizeImage(app.currentImage, 'tritanopia');
                otherwise
                    return;
            end
        
            % Generate unique temporary filenames
            enhancedImgPath = [tempname, '.png'];
            diffImgPath = [tempname, '.png'];
        
            % Compute and display the difference
            diffImg = computeDifference(app.currentImage, enhancedImg);
            
            % Write images to temporary files
            imwrite(enhancedImg, enhancedImgPath);
            imwrite(diffImg, diffImgPath);
        
            % Update app UI with new images
            app.ImageOutput.ImageSource = enhancedImgPath;
            app.ImageDifference.ImageSource = diffImgPath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ColorBlindSimulationTab = uitab(app.TabGroup);
            app.ColorBlindSimulationTab.Title = 'Color Blind Simulation';
            app.ImageProtanopia = uiimage(app.ColorBlindSimulationTab);
            app.LoadNormal = uibutton(app.ColorBlindSimulationTab, 'push');
            app.LoadNormal.ButtonPushedFcn = createCallbackFcn(app, @LoadNormalButtonPushed, true);
            app.LoadNormal.Text = 'Load';
            app.ImageTritanopia = uiimage(app.ColorBlindSimulationTab);
            app.ImageDeuteranopia = uiimage(app.ColorBlindSimulationTab);
            app.ImageNormal = uiimage(app.ColorBlindSimulationTab);
            app.NormalVisionLabel = uilabel(app.ColorBlindSimulationTab);
            app.NormalVisionLabel.Text = 'Normal Vision';
            app.ProtanopiaLabel = uilabel(app.ColorBlindSimulationTab);
            app.ProtanopiaLabel.Text = 'Protanopia';
            app.DeuteranopiaLabel = uilabel(app.ColorBlindSimulationTab);
            app.DeuteranopiaLabel.Text = 'Deuteranopia';
            app.TritanopiaLabel = uilabel(app.ColorBlindSimulationTab);
            app.TritanopiaLabel.Text = 'Tritanopia';
            app.ImageRecoloringTab = uitab(app.TabGroup);
            app.ImageRecoloringTab.Title = 'Image Recoloring';
            app.ImageOutput = uiimage(app.ImageRecoloringTab);
            app.LoadInput = uibutton(app.ImageRecoloringTab, 'push');
            app.LoadInput.ButtonPushedFcn = createCallbackFcn(app, @LoadInputPushed, true);
            app.LoadInput.Text = 'Load';
            app.ColorBlindTypeDropDownLabel = uilabel(app.ImageRecoloringTab);
            app.ColorBlindTypeDropDownLabel.Text = 'Color Blind Type';
            app.ColorBlindTypeDropDown = uidropdown(app.ImageRecoloringTab);
            app.ColorBlindTypeDropDown.Items = {'Protanopia', 'Deuteranopia', 'Tritanopia'};
            app.ColorBlindTypeDropDown.Value = 'Protanopia';
            app.ImageInput = uiimage(app.ImageRecoloringTab);
            app.ImageDifference = uiimage(app.ImageRecoloringTab);
            app.NormalVisionLabel_2 = uilabel(app.ImageRecoloringTab);
            app.NormalVisionLabel_2.Text = 'Normal Vision';
            app.RecoloredImageLabel = uilabel(app.ImageRecoloringTab);
            app.RecoloredImageLabel.Text = 'Recolored Image';
            app.ColorDifferenceLabel = uilabel(app.ImageRecoloringTab);
            app.ColorDifferenceLabel.Text = 'Color Difference';
            app.Recolor = uibutton(app.ImageRecoloringTab, 'push');
            app.Recolor.ButtonPushedFcn = createCallbackFcn(app, @RecolorButtonPushed, true);
            app.Recolor.Text = 'Recolor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end